classdef ProjectPPT_21200325 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DrawgraphsButton             matlab.ui.control.Button
        bieuthucTextArea             matlab.ui.control.TextArea
        bieuthucTextAreaLabel        matlab.ui.control.Label
        nhap_YEditField              matlab.ui.control.EditField
        nhap_YEditFieldLabel         matlab.ui.control.Label
        nhap_XEditField              matlab.ui.control.EditField
        nhap_XEditFieldLabel         matlab.ui.control.Label
        resultEditField              matlab.ui.control.EditField
        resultEditFieldLabel         matlab.ui.control.Label
        calculationButton            matlab.ui.control.Button
        phuong_phap_tinhButtonGroup  matlab.ui.container.ButtonGroup
        NewtonButton                 matlab.ui.control.RadioButton
        LarangeButton                matlab.ui.control.RadioButton
        nhapxiEditField              matlab.ui.control.NumericEditField
        nhapxiEditFieldLabel         matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

 
    methods (Access = private)
        % Ham noi suy Lagrange
        function [Lagrange_polynomial, result_Lagrange] = calculateLagrange(~, x_data, y_data, xi)
             x = sym('x');
            Lagrange_polynomial = 0;

            n = length(x_data);

            for i = 1:n
                l_i = 1;
                for j = 1:n
                    if j ~= i
                        l_i = l_i * (x - x_data(j)) / (x_data(i) - x_data(j));
                    end
                end
                Lagrange_polynomial = Lagrange_polynomial + y_data(i) * l_i;
            end

            result_Lagrange = vpa(subs(Lagrange_polynomial, x, xi), 15);
        end

        % Ham noi suy Newton
        function [Newton_polynomial, result_Newton] = calculateNewton(~, x_data, y_data, xi)
            n = length(x_data);
            Y = zeros(n, n);
            Y(:, 1) = y_data;

            for j = 2:n
                for i = 1:n-j+1
                    Y(i, j) = (Y(i+1, j-1) - Y(i, j-1)) / (x_data(i+j-1) - x_data(i));
                end
            end

            syms x;
            Newton_polynomial = Y(1, 1);

            for j = 2:n
                term = prod(x - x_data(1:j-1));
                Newton_polynomial = Newton_polynomial + Y(1, j) * term;
            end

             result_Newton = vpa(subs(Newton_polynomial, x, xi), 15);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculationButton
        function calculationButtonPushed(app, event)
         selectedButton = app.phuong_phap_tinhButtonGroup.SelectedObject.Text;
            xi = app.nhapxiEditField.Value;
            
            X_str = app.nhap_XEditField.Value;
            Y_str = app.nhap_YEditField.Value;
        
        
            x_data = eval(['[', X_str, ']']);
            y_data = eval(['[', Y_str, ']']);


            switch selectedButton
                case 'Larange'
                    [Lagrange_polynomial, result_Lagrange] = calculateLagrange(app, x_data, y_data, xi);
                    app.bieuthucTextArea.Value = char(Lagrange_polynomial);
                    app.resultEditField.Value = char(result_Lagrange);
        
                case 'Newton'
                    [Newton_polynomial, result_Newton] = calculateNewton(app, x_data, y_data, xi);
                    app.bieuthucTextArea.Value = char(Newton_polynomial);
                    app.resultEditField.Value = char(result_Newton);
            end                     
            
        end

        % Button pushed function: DrawgraphsButton
        function DrawgraphsButtonPushed(app, event)
            selectedButton = app.phuong_phap_tinhButtonGroup.SelectedObject.Text;

            X_str = app.nhap_XEditField.Value;
            Y_str = app.nhap_YEditField.Value;
            x_data = eval(['[', X_str, ']']);
            y_data = eval(['[', Y_str, ']']);
    
            % Ve cac diem data, danh dau o
            plot(app.UIAxes, x_data, y_data, 'o', 'DisplayName', 'Data Points');
            hold(app.UIAxes, 'on');
    
            % Ve duong do thi
            switch selectedButton
                case 'Larange'
                    xi = linspace(min(x_data), max(x_data), 1000);
                    [Lagrange_polynomial, ~] = calculateLagrange(app, x_data, y_data, xi);
                    Lagrange_values = double(subs(Lagrange_polynomial, xi));
                    plot(app.UIAxes, xi, Lagrange_values, 'DisplayName', 'Lagrange Polynomial');
               
                
                case 'Newton'
                    xi = linspace(min(x_data), max(x_data), 1000);
                    [Newton_polynomial, ~] = calculateNewton(app, x_data, y_data, xi);
                    Newton_values = double(subs(Newton_polynomial, xi));
                    plot(app.UIAxes, xi, Newton_values, 'DisplayName', 'Newton Polynomial');
               
            
            end
            
            % Danh dau vi tri X
            hold(app.UIAxes, 'on');
            switch selectedButton
                case 'Larange'
                    plot(app.UIAxes, app.nhapxiEditField.Value, double(subs(Lagrange_polynomial, app.nhapxiEditField.Value)), 'bX', 'DisplayName', 'Result','MarkerSize', 10);
                case 'Newton'
                    plot(app.UIAxes, app.nhapxiEditField.Value, double(subs(Newton_polynomial, app.nhapxiEditField.Value)), 'bX', 'DisplayName', 'Result','MarkerSize', 10);
            end
    
            
            title(app.UIAxes, 'Interpolating Polynomial');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            legend(app.UIAxes, 'Location', 'Best');
            hold(app.UIAxes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.nhapxiEditFieldLabel = uilabel(app.UIFigure);
            app.nhapxiEditFieldLabel.Text = 'nhap xi';
            app.nhapxiEditField = uieditfield(app.UIFigure, 'numeric');
            app.phuong_phap_tinhButtonGroup = uibuttongroup(app.UIFigure);
            app.phuong_phap_tinhButtonGroup.Title = 'phuong_phap_tinh';
            app.LarangeButton = uiradiobutton(app.phuong_phap_tinhButtonGroup);
            app.LarangeButton.Text = 'Larange';
            app.LarangeButton.Value = true;
            app.NewtonButton = uiradiobutton(app.phuong_phap_tinhButtonGroup);
            app.NewtonButton.Text = 'Newton';
            app.calculationButton = uibutton(app.UIFigure, 'push');
            app.calculationButton.ButtonPushedFcn = createCallbackFcn(app, @calculationButtonPushed, true);
            app.calculationButton.Text = 'calculation';
            app.resultEditFieldLabel = uilabel(app.UIFigure);
            app.resultEditFieldLabel.Text = 'result';
            app.resultEditField = uieditfield(app.UIFigure, 'text');
            app.nhap_XEditFieldLabel = uilabel(app.UIFigure);
            app.nhap_XEditFieldLabel.Text = 'nhap_X';
            app.nhap_XEditField = uieditfield(app.UIFigure, 'text');
            app.nhap_YEditFieldLabel = uilabel(app.UIFigure);
            app.nhap_YEditFieldLabel.Text = 'nhap_Y';
            app.nhap_YEditField = uieditfield(app.UIFigure, 'text');
            app.bieuthucTextAreaLabel = uilabel(app.UIFigure);
            app.bieuthucTextAreaLabel.Text = 'bieu thuc';
            app.bieuthucTextArea = uitextarea(app.UIFigure);
            app.DrawgraphsButton = uibutton(app.UIFigure, 'push');
            app.DrawgraphsButton.ButtonPushedFcn = createCallbackFcn(app, @DrawgraphsButtonPushed, true);
            app.DrawgraphsButton.Text = 'Draw graphs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectPPT_21200325

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end